classdef electronics_control_9_S5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        maxbubblingtimeLabel           matlab.ui.control.Label
        bubblingIOButton               matlab.ui.control.StateButton
        serialportnoCOMEditField_4     matlab.ui.control.EditField
        runssseqButton                 matlab.ui.control.Button
        spinsolvectrlButton            matlab.ui.control.StateButton
        PG4redButton                   matlab.ui.control.StateButton
        shutdownButton                 matlab.ui.control.StateButton
        autosaveButton                 matlab.ui.control.StateButton
        saveButton                     matlab.ui.control.Button
        cleargraphButton               matlab.ui.control.Button
        graphButton                    matlab.ui.control.StateButton
        PG3greenButton                 matlab.ui.control.StateButton
        PG2blueButton                  matlab.ui.control.StateButton
        PG1blackButton                 matlab.ui.control.StateButton
        flushtimesEditField            matlab.ui.control.NumericEditField
        flushtimesEditFieldLabel       matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        Lamp                           matlab.ui.control.Lamp
        lampButton                     matlab.ui.control.StateButton
        serialportnoCOMEditFieldLabel  matlab.ui.control.Label
        serialportnoCOMEditField       matlab.ui.control.EditField
        ShortValveButton               matlab.ui.control.StateButton
        OutValveButton                 matlab.ui.control.StateButton
        InValveButton                  matlab.ui.control.StateButton
        OPHValveButton                 matlab.ui.control.StateButton
        NNvalveButton                  matlab.ui.control.StateButton
        manualcontrolButton            matlab.ui.control.StateButton
        disconnectButton               matlab.ui.control.Button
        connectButton                  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        GotopositionButton             matlab.ui.control.Button
        serialportnoCOMEditField_3     matlab.ui.control.EditField
        CalibrationButton              matlab.ui.control.StateButton
        Movedown5mmButton              matlab.ui.control.Button
        Moveup5mmButton                matlab.ui.control.Button
        SlowdescentButton              matlab.ui.control.StateButton
        SlowascentButton               matlab.ui.control.StateButton
        ReadpositionButton             matlab.ui.control.Button
        DisconnectmotorButton          matlab.ui.control.Button
        Sample6mTButton                matlab.ui.control.Button
        SampledownButton               matlab.ui.control.Button
        HallprobedownButton            matlab.ui.control.Button
        SpinsolvemotorcontrolsLabel    matlab.ui.control.Label
        STOPButton                     matlab.ui.control.Button
        ConnectmotorButton             matlab.ui.control.Button
        FullyupButton                  matlab.ui.control.Button
        FullydownButton                matlab.ui.control.Button
        serialportnoCOMEditField_2     matlab.ui.control.EditField
        serialportnoCOMEditField_2Label  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


%% I'm so sorry. 


% global vars
    properties (Access = private)
        Property % Description
        %
        overrideOPH = 0; %
        overrideIn = 0;
        overrideOut = 0;
        overrideShort = 0;
        overrideNN = 0;
        SerialComPort = "COM4"

        %lines in the figure
        pressure1 = animatedline();
        pressure2 = animatedline('Color','b');
        pressure3 = animatedline('Color','g');
        pressure4 = animatedline('Color','r');

        a = 0;                      % Serial Connection to eBox
        Log = {};                   %text log
        graphing;                   %state of graph
        scon = 0;                   %serial connection state
        startTime = datetime("now");
        savetime %= datestr(startTime, 'yymmmdd_HHMMSS');
        t;
        flushTime;
        axe;
        %newsave = [time, pressures: voltage2, pressure2, v3, ..., valve States: h2, in, out, short, n2];
        %savegraph  [t v p v p v p v p h i o s n d]
        %savecount  [1 2 3 4 5 6 7 8 9 A B C D E F] - number of things l to r in the matrix.
        savegraph = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
        savegrDef = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
        saveCell  = {'time/s', 'V/coil', 'P/coil', 'V/PG1', 'P/Outlet', 'V/PG2', 'P/Inlet', 'V/PG3', 'P/Cell', ' Cell dP', 'V5(opH)', 'V1(In)', 'V2(out)', 'V4(Short)', 'V3(N2)'};
        graphtime = 0;
        elapsed;                    %time since app started running (calculated each step of graphing)
        pNos;
        pStr;
        voidStr = "";               %empty string
        saveLoc;                    %file save location
        saveXL;
        %%%%%%%%%%%%%%%%%%%%%%%%

        pulseHi;
        pulseLo;
        pulseDur;
        pulseT;

        m = 0;
        mCon = 0;
        mCal = 0; % motor calibration mode
        position;
        MotorComPort = 'COM5';
       % maxPos = 2500000;
        maxPos = 0;
      % maxPosSample = 2493343;
        maxPosSample = -6657;
      % minPos = 1874875; %test
        minPos = -625125
        curPos = 2500000;
        stepCal = 8000;
        shutdown = 0; 


    end
%functions
    methods (Access = private)

        %   GENERAL 

        function logit(app,text)
            app.Log = [app.Log, text];
            app.TextArea.Value = app.Log;
            pause(0.5)
            scroll(app.TextArea, 'bottom')

        end

        %   ARDUINO/VALVES

        function vlvPwr(app, onCha, offCha, value, ontext, offtext)     % valve power finction. characters sent over
            if value == 1                                               % Serial to arduino, and logged in the text box
                write(app.a, onCha, 'char');                            %turns on the valve through manual control
                app.logit(ontext)
            end
            if value == 0
                write(app.a, offCha, 'char');
                app.logit(offtext)
            end

        end

        function [v, p] = handlePressure(app, n, c, m, line) % PG no., intercept, gradient

            % computes the actual differential pressure
            %in bar from the  10-bit number supplied
            %the intercept and gradient are from calibration data using the
            %input result compared to the digital gauge on the coil.
            v = app.pNos(n);
            p = (v-c)/m;
            if p < 0
                p = 0;
            end
            datenum(app.t)
            addpoints(line, datenum(app.t), p)

        end

        function handlePressureIn(app)

            ax = gca;

            while app.graphButton.Value == 1
                try
                    app.pStr = readline(app.a)
                catch
                    pause(10)
                    write(app.a, 'K', 'char')
                    app.logit('pdata read failed')
                    pause(1)
                    app.pStr = readline(app.a)
                end
                try
                    pcheck = extract(app.pStr,1);
                    if pcheck == 'P'
                        pRead = 1;
                    elseif pcheck == 'I'

                        %%AUTOPULSE INITIALISER.
                        %I am assuming you are graphing if you are running
                        %experiments.
                        %If this changed, something will need to be added.
                        %autopulse is flagged here
                        %autoPulse();
                    end
                catch
                    pRead = 0;
                    app.logit('no pressure data recieved')
                end

                if pRead == 1
                    app.pNos = extractBetween(app.pStr, 'P', 'C')
                    app.pNos = str2num(app.pNos)
                    app.pStr
                    app.elapsed = datetime('now');
                    app.t = app.elapsed - app.startTime; %gets time since we started


                    % A0 A2 A4 A6
                    %                   voltage, pressure = calculate( PG no., intercept,
                    %                   gradient, graph line
                    if (app.PG2blueButton.Value) == false
                        [v1, p1] = app.handlePressure(1, 28, 134, app.pressure1);
                    else
                        v2 = app.voidStr;
                        p2 = app.voidStr;
                    end

                    if (app.PG2blueButton.Value) == false
                        [v2, p2] = app.handlePressure(2, 28, 134, app.pressure2);
                    else
                        v2 = app.voidStr;
                        p2 = app.voidStr;
                    end

                    if app.PG3greenButton.Value == false
                        [v3, p3] = app.handlePressure(3, 39, 134, app.pressure3);
                    else
                        v3 = app.voidStr;
                        p3 = app.voidStr;
                    end
                    if app.PG4redButton.Value == false       % p = (v-c)/m;  hp(g c m l)
                        [v4, p4] = app.handlePressure(4, 200, 83, app.pressure4);
                    else
                        v4 = app.voidStr;
                        p4 = app.voidStr;
                    end

                    % reads the pressure off the coil.

                    if isnumeric(p3) && isnumeric(p4) == 1
                        pDif = p3 - p4;
                    else
                        pDif = app.voidStr;
                    end
                    %app.logit('pDif')
                    valveState = app.pNos(4:8);
                    valveState = (0.5*valveState) + [0 1 2 3 4];
                    actualtime = seconds(app.t);
                    newsave = [actualtime, v1, p1, v2, p2, v3, p3, v4, p4, pDif, valveState]
                    app.savegraph = [app.savegraph; newsave]
                    % app.graphtime = [app.graphtime, actualtime];
                    % app.fullsave = [app.savegraph; app.graphtime]
                    ax.XLim = datenum([((app.t)-seconds(15)) app.t]);
                    datetick('x','keeplimits');
                    drawnow


                    %% Autosave - clear
                    if (app.autosaveButton.Value == 1 && isfile("C:\matlabsaves\_autosave\clear.txt"))
                        delete C:\matlabsaves\_autosave\clear.txt;
                        if (actualtime >= 5)
                            filePlace = fileread("C:\matlabsaves\_autosave\autosave.txt");
                            app.savetime = datestr(app.startTime, 'yymmmdd_HHMMSS');
                            savename = "\pre-expt-data" + app.savetime + ".csv";
                            app.saveLoc = filePlace + savename;
                            app.savePData;
                        end
                        app.clearGraph();
                    end

                    %%Autosave - save data

                    %checks for the existence of 'dosave' as an instruction
                    %to run the saving routine
                    if (isfile("C:\matlabsaves\_autosave\dosave.txt") && app.autosaveButton.Value == 1)

                        %gets the saving location from dosave.txt
                        %saves the file in that location with date and time
                        filePlace = fileread("C:\matlabsaves\_autosave\autosave.txt");
                        app.savetime = datestr(app.startTime, 'yymmmdd_HHMMSS');
                        savename = "\_expt_" + app.savetime + ".csv";
                        %set save location
                        app.saveLoc = filePlace + savename;
                        %print that saving is done
                        app.logit(app.saveLoc);
                        %execute the saving function
                        app.savePData;
                        %clear the graph
                        app.clearGraph();

                        delete C:\matlabsaves\_autosave\dosave.txt
                    end
                end
            end
        end

        function Spinsolver(app)

            while app.spinsolvectrlButton.Value == 1

                tic
                pause(0.002);       % add loop delay parameter


                if isfile("C:\ssbubble\sequence.txt") && isfile('C:\ssbubble\sendseq.txt')
                    try
                        sequence = fileread("C:\ssbubble\sequence.txt");
                        % class(sequence)
                    catch
                        app.logit("unable to read sequence")
                        % write an error:stop file back to prospa
                        errCode = 999;
                        save("C:\ssbubble\error.txt", "errCode","-ascii");
                        app.spinsolvectrlButton.Value = 0;
                        break
                    end
                    write(app.a, 'i', 'char')
                    pause(2)
                    toc
                    write(app.a, sequence, 'char')
                    toc
                    app.logit(("sent " + sequence))
                    toc
                    delete C:\ssbubble\sendseq.txt
                end
                if isfile('C:\ssbubble\runSeq.txt')
                    try
                        str2double(sequence(1));
                        app.logit(sequence(1));

                        if (str2double(sequence(1)) > 0)
                            app.logit('running ssseq');
                            pause(0.5);
                            write(app.a, 'R', 'char');
                            delete C:\ssbubble\runSeq.txt;
                        end
                    end
                end
                toc
            end
        end
       
        function savePData(app)                                 % saves all collected pressure data to saveLocation,


            writecell(app.saveCell, app.saveLoc)
            writematrix(app.savegraph, app.saveLoc, 'WriteMode','append');            % then clears the graph and restarts graphing.
            app.logit('saved');
            app.logit(app.saveLoc);

            %xlsav = readmatrix(app.saveLoc);
            %writecell

            clear app.savegraph;
            app.savegraph = app.savegrDef;
            clear app.startTime
            app.clearGraph()
        end

        function clearGraph(app)
            clearpoints(app.pressure1)
            clearpoints(app.pressure2)
            clearpoints(app.pressure3)
            clearpoints(app.pressure4)
            clear app.savegraph
            zero = datetime('now');
            app.t = zero - zero;
            app.startTime = datetime('now');
            app.savetime = datestr(app.startTime, 'yymmmdd_HHMMSS');
            app.logit('graph cleared');
        end

        function connectToArd(app)

            app.scon = 1;

            try
                if app.scon == 1
                    app.a = serialport(app.SerialComPort, 9600); %app.SerialComPort = 'COM4' by default, set above.
                    app.logit('connected');   %tries to make a connection to the eBOx down the set COM port
                    app.TextArea.Value = app.Log;
                    % pause (10)
                    % write(app.a, 'I', 'char')
                end
            catch
                app.scon                                %here to give an error if connection  fails
                app.scon = 0;                            %usually fails because the COM port is wrong
                app.logit('no arduino found');
                app.TextArea.Value = app.Log;
            end
        end
       
        %%%     M0TOR      

        function connectMotor(app)
            try
                app.m = serialport(app.MotorComPort, 4800); %app.SerialComPort = 'COM4' by default, set above.
                app.logit('connected');   %tries to make a connection to the eBOx down the set COM port                end
                app.mCon = 1;
            catch
                app.logit('no device found');
            end

            pause(0.1)
            app.commandMotor('setPars', 4, 2047);
% default parameters
% SAP type 4 val 2047
% SAP type 5 val 2047
% SAP type 6 val 240 (try 255? maybe lower?)
% SAP type 7 val 8 
% SAP type 140 val 6
% SAP type 153 val 8
% SAP type 154 val 1
            try
                app.commandMotor('setPars', 4, 2047); %max posn speed
                app.commandMotor('setPars', 5, 2047); %max accel
                app.commandMotor('setPars', 6, 240);  %max I
                app.commandMotor('setPars', 7, 8);    %ustep res
                app.commandMotor('setPars', 140, 6);  
                app.commandMotor('setPars', 153, 8);
                app.commandMotor('setPars', 154, 1);
                app.logit('pars set'); 
            catch
                app.logit('motor parameter setting failed')
            end
            while app.mCon == 1
                try
                app.readMotorSerial()
                
                catch
                end
            end
        end

        function positionMotor(app, position)

            class app.mCal
            if app.mCal ~= 1
                if position > app.maxPosSample && position > app.curPos
                    app.logit(num2str(position))
                    app.logit("Requested position will cause crash! Aborted.")
                    return;
                elseif position < app.minPos && position < app.curPos
                    app.logit(num2str(position))
                    app.logit("Requested position will cause crash! Aborted.")
                    return;
                end


            end
            %% pars for building the checksum and a complete message
            address = 1;
            cmd = 4;
            type = 0;
            motor = 0;
            value_data = position;
            BYTE = 8;
            NB_VALUE_BYTES    = 4;
            value_4B  = dec2bin(typecast(int32(value_data),'uint32'),4*BYTE);
            value_sum = 0;

            %% setup to write
            value     = zeros(1,NB_VALUE_BYTES);
            value_4B  = dec2bin(typecast(int32(value_data),'uint32'),NB_VALUE_BYTES*BYTE);
            % NB_VALUE_BYTES:-1:1;
            for i=NB_VALUE_BYTES:-1:1
                value(i)  = bin2dec(value_4B((i-1)*BYTE+1:i*BYTE));
                value_sum = value_sum + value(i);
            end
            value_sum_B = dec2bin(value_sum,BYTE);
            checksum = address + cmd+type+motor+bin2dec(value_sum_B(end-BYTE+1:end));
            % Message construction
            message = [address,cmd,type,motor,value,checksum];
            try
                write(app.m, message,'uint8');
            catch
                app.logit("move failed");
            end

        end

        function val = readMotorPos(app)

            app.commandMotor("get", 1, 0);
            val = (app.readMotorSerial);
            msg = num2str(val);
            app.logit(num2str(msg));
        end

        function response = readMotorSerial(app)
            try
            reply = fread(app.m, 9, 'uint8');
            read_value   = reply(5:8)';
            value_C      = dec2bin(read_value,8);
            value_4C     = [value_C(1,:) value_C(2,:) value_C(3,:) value_C(4,:)];
            response = typecast(uint32(bin2dec(value_4C)),'int32');
            catch
            end
            
        end
           
        function commandMotor(app, command, type, value_data)

            %pars for building the checksum and a complete message

                        % set the command type by converting to an integer
            address = 1;
            switch command
                case "rdn"
                    cmd = 1;
                case "rup"
                    cmd = 2;
                case "stop"
                    cmd = 3;
                case "pos"
                    cmd = 4;
                case "setPars"
                    cmd = 5;
                case "get"
                    cmd = 6;
                otherwise
                    return;
            end
            motor = 0;
            BYTE = 8;
            NB_VALUE_BYTES    = 4;
            value_4B  = dec2bin(typecast(int32(value_data),'uint32'),4*BYTE);
            value_sum = 0;

            % setup to write
            value     = zeros(1,NB_VALUE_BYTES);
            value_4B  = dec2bin(typecast(int32(value_data),'uint32'),NB_VALUE_BYTES*BYTE)
            value_sum = 0;
            % NB_VALUE_BYTES:-1:1;
            for i=NB_VALUE_BYTES:-1:1
                value(i)  = bin2dec(value_4B((i-1)*BYTE+1:i*BYTE));
                value_sum = value_sum + value(i);
            end
            value_sum_B = dec2bin(value_sum,BYTE);
            checksum = address + cmd+type+motor+bin2dec(value_sum_B(end-BYTE+1:end))
            % Message construction
            message = [address,cmd,type,motor,value,checksum]
            try
                write(app.m, message,'uint8')
            catch
                app.logit("cmd failed")
            end

        end

        function mil = pos2mil(pos)
            mil = (app.maxPos - posn)/app.stepCal;
        end

        function posn = mil2pos(mil)
            pos = mil*app.stepCal;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ard)
            %as much as i try i cannot get the graph to plot in the UI
            %window, only a separate figure. if you know how to do this,
            %please do implement it and let me know how you did it

            %app.axe = axes(app.UIFigure);
            %app.axe.Units = 'pixels';


            set(app.OPHValveButton, 'Enable', 'off');           %makes tha manual ovverides off by default
            set(app.NNvalveButton, 'Enable', 'off');
            set(app.InValveButton, 'Enable', 'off');
            set(app.OutValveButton, 'Enable', 'off');
            set(app.ShortValveButton, 'Enable', 'off');
            set(app.bubblingIOButton, 'Enable', 'off');
            set(app.PG1blackButton, 'Enable', 'on');
            set(app.PG2blueButton, 'Enable', 'on');
            set(app.PG3greenButton, 'Enable', 'on');
            %set(app.PG1blackButton, 'on');
            %set(app.PG2blueButton, 'on');
            %set(app.PG3greenButton, 'on');

            app.serialportnoCOMEditField_4.Value = '0';
                        app.serialportnoCOMEditField.Value = '4';

            app.logit('Startup');
            app.TextArea.Value = app.Log;
            app.a = [];
            app.savetime = datestr(app.startTime, 'yymmmdd_HHMMSS');
            tic;

            try
                if app.scon == 1 %tries to make a connection to the serial port.
                    app.SerialComPort = 'COM4'; %default com port connection. if this fails we dont try again unprompted
                    app.a = serialport(app.SerialComPort, 9600); %as set below.
                    app.logit('connected'); %logs our connection
                    app.TextArea.Value = app.Log;
                    pause('on')
                    pause(10)
                    write(app.a, 'I', 'char')           % hoping to use this to pass parameters acrpss to the adruino ( polling time, etc.) unused rn.
                end
            catch
                app.scon;
                app.scon = 0;
                app.logit('no arduino found');
            end
        end

        % Button pushed function: connectButton
        function connectButtonPushed(app, event)
            app.connectToArd()
        end

        % Button pushed function: disconnectButton
        function disconnectButtonPushed(app, event)
            app.graphButton.Value = 0;
            app.scon = 0;
            try
                % write(app.a, 'i', 'char')
            catch
            end
            clear app.a
            app.a = [];
            app.logit('disconnected')

        end

        % Value changed function: lampButton
        function lampButtonValueChanged(app, event)
            app.lampButton.Value; % used to test bits
            app.Lamp.Color = 'r';
            app.Lamp.Enable = value;
            app.logit(app,'lamp')
        end

        % Value changed function: graphButton
        function graphButtonValueChanged(app, event)
            app.graphButton.Value;
            app.graphing = app.graphButton.Value;


            if app.scon == 0
                app.logit ('no device connected' )
                try
                    app.connectToArd()
                catch
                    app.graphButton.Value = 0;
                    app.logit('pressure unavailable')

                    %unhelpful comment: i suspect this if loop is entirely
                    %redundant.
                    if app.graphing == 0
                        write(app.a, 'K', "char")
                        app.logit ('trying connection');
                    end
                end
                app.graphButton.Value = 0;
            end


            if app.scon == 1
                if app.graphing == 1
                    write(app.a, 'K', 'char')               %tell box to start sending data

                    if hours(app.t) < 0.0003                %set the timer if it isn't already going
                        app.startTime = datetime('now');
                    end


                    app.logit('graphing on');
                    app.handlePressureIn();
                end
            end


        end

        % Value changed function: manualcontrolButton
        function manualcontrolButtonValueChanged(app, event)
            value = app.manualcontrolButton.Value;
            if value == 1
                write(app.a, 'm', 'char');
            end
            if value == 0
                write(app.a, 'M', 'char');

            end
            if value == 0
                set(app.OPHValveButton, 'Enable', 'off');
                set(app.NNvalveButton, 'Enable', 'off');
                set(app.InValveButton, 'Enable', 'off');
                set(app.OutValveButton, 'Enable', 'off');
                set(app.ShortValveButton, 'Enable', 'off');
                set(app.bubblingIOButton, 'ENable', 'off');
            else
                set(app.OPHValveButton, 'Enable', 'on');
                set(app.NNvalveButton, 'Enable', 'on');
                set(app.InValveButton, 'Enable', 'on');
                set(app.OutValveButton, 'Enable', 'on');
                set(app.ShortValveButton, 'Enable', 'on');
                set(app.bubblingIOButton, 'ENable', 'on');
            end
        end

        % Value changed function: NNvalveButton
        function NNvalveButtonValueChanged(app, event)
            value = app.NNvalveButton.Value;
            if value == 1
                write(app.a, 'X', 'char');
                %flushtext = num2str(app.flushTime);
                app.logit('flushing')
                %                 pause(app.flushTime);
                %                 write(app.a, 'x', 'char');
                %                 set(app.NNvalveButton, 'Value', 0)
                %
            end
            if value == 0
                write(app.a, 'x', 'char');

            end
        end

        % Value changed function: serialportnoCOMEditField
        function serialportnoCOMEditFieldValueChanged(app, event)
            value = app.serialportnoCOMEditField.Value;
            app.SerialComPort = strcat('COM',value);
            app.SerialComPort
            logit(app, 'port updated')
        end

        % Button pushed function: cleargraphButton
        function cleargraphButtonPushed(app, event)
            app.clearGraph()
        end

        % Value changed function: OPHValveButton
        function OPHValveButtonValueChanged(app, event)
            value = app.OPHValveButton.Value;
            vlvPwr(app, 'H', 'h', value, 'H mix', 'para-H2')
        end

        % Value changed function: InValveButton
        function InValveButtonValueChanged(app, event)
            value = app.InValveButton.Value;
            vlvPwr(app, 'C', 'c', value, 'inlet open', 'inlet closed')
        end

        % Value changed function: OutValveButton
        function OutValveButtonValueChanged(app, event)
            value = app.OutValveButton.Value;
            vlvPwr(app, 'V', 'v', value, 'outlet open', 'outlet closed')
            %if value == 1
            %    write(app.a, 'V', 'char');
            %    app.Log = [app.Log, 'outlet open'];
            %    app.TextArea.Value = app.Log;
            %end
            %if value == 0
            %    write(app.a, 'v', 'char');
            %    app.Log = [app.Log, 'outlet closed'];
            %    app.TextArea.Value = app.Log;
            %end
        end

        % Value changed function: ShortValveButton
        function ShortValveButtonValueChanged(app, event)
            value = app.ShortValveButton.Value;
            vlvPwr(app, 'Z', 'z', value, 'short open', 'short closed')
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Value changed function: flushtimesEditField
        function flushtimesEditFieldValueChanged(app, event)
            value app.flushtimesEditField.Value;
            app.flushTime = value;
            s1 = 'NN flush time is ';
            flushtext = num2str(app.flushTime);
            fusht = strcat(s1 + flushtext);
            app.logit(fusht);
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)

            if app.autosaveButton.Value == 0 || isempty(app.saveLoc) == 1
                app.saveLoc = "C:\matlabsaves\" + app.savetime + ".csv"; %Z:\James M\savedata\
            end
            app.savePData
            % writematrix (app.pressure2, savename2)

        end

        % Value changed function: PG1blackButton
        function PG1blackButtonValueChanged(app, event)
            app.PG1blackButton.Value;

        end

        % Value changed function: PG2blueButton
        function PG2blueButtonValueChanged(app, event)
            app.PG2blueButton.Value;

        end

        % Value changed function: PG3greenButton
        function PG3greenButtonValueChanged(app, event)
            app.PG3greenButton.Value;

        end

        % Value changed function: serialportnoCOMEditField_2
        function serialportnoCOMEditField_2ValueChanged(app, event)
            value = app.serialportnoCOMEditField_2.Value;

        end

        % Callback function
        function pulsetotaldurationsEditFieldValueChanged(app, event)
            value = app.pulsetotaldurationsEditField.Value;
            app.pulseDur = value;
        end

        % Callback function
        function pulsetimemsEditFieldValueChanged(app, event)
            value = app.pulsetimemsEditField.Value;
            app.pulseT = value;
        end

        % Callback function
        function pulsehighpowermAEditFieldValueChanged(app, event)
            value = app.pulsehighpowermAEditField.Value;
            app.pulseHi = value;
        end

        % Callback function
        function pulselowpowermAEditFieldValueChanged(app, event)
            value = app.pulselowpowermAEditField.Value;
            app.pulseLo = value;
        end

        % Callback function
        function runpulsesButtonValueChanged(app, event)
            value = app.runpulsesButton.Value;
            if value == 1 && app.s2con == 1

            end
        end

        % Value changed function: autosaveButton
        function autosaveButtonValueChanged(app, event)
            value = app.autosaveButton.Value;
            printstep = 0
            if value == 1
                app.savetime = datestr(app.startTime, 'yymmmdd_HHMMSS');
                if isfolder("C:\matlabsaves\_autosave") == 0
                    mkdir("C:\matlabsaves\_autosave")
                    app.logit("folder made")
                end
                %                 if isfile("C:\matlabsaves\_autosave\autosave.txt")
                %                     filePlace = fileread("C:\matlabsaves\_autosave\autosave.txt");
                %                     app.saveLoc = filePlace + app.savetime + ".csv"
                %                     app.logit(app.saveLoc);
                %                 end
            else
                app.saveLoc = "C:\matlabsaves\" + app.savetime + ".csv";
                app.logit(app.saveLoc);
            end

        end

        % Value changed function: PG4redButton
        function PG4redButtonValueChanged(app, event)
            value = app.PG4redButton.Value;
            
        end

        % Value changed function: spinsolvectrlButton
        function spinsolvectrlButtonValueChanged(app, event)
            value = app.spinsolvectrlButton.Value;
            if value == 1
                app.logit("spinsolve ctrl");
            end

            app.Spinsolver()
        end

        % Button pushed function: runssseqButton
        function runssseqButtonPushed(app, event)
            write(app.a, 'R', 'char')
        end

        % Value changing function: serialportnoCOMEditField_2
        function serialportnoCOMEditField_2ValueChanging(app, event)
            changingValue = string(event.Value)
            class(changingValue)

            app.MotorComPort = strcat('COM', changingValue)
            app.MotorComPort
            logit(app, 'port updated')
        end

        % Button pushed function: FullydownButton
        function FullydownButtonPushed(app, event)
            app.positionMotor(app.maxPos)
        end

        % Button pushed function: FullyupButton
        function FullyupButtonPushed(app, event)
            pos = app.maxPos - 625125
            app.positionMotor(pos)
        end

        % Button pushed function: ConnectmotorButton
        function ConnectmotorButtonPushed(app, event)
            app.connectMotor()
        end

        % Callback function
        function mTButtonPushed(app, event)
            pos = app.maxPos - 340934
            app.positionMotor(pos)
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.commandMotor('stop', 1, 1)
            app.SlowdescentButton.Value = 0;
            app.SlowdescentButton.Value = 0;
        end

        % Button pushed function: HallprobedownButton
        function HallprobedownButtonPushed(app, event)
            pos = app.maxPos - 21904
            app.positionMotor(pos)
        end

        % Button pushed function: SampledownButton
        function SampledownButtonPushed(app, event)
            pos = app.maxPos - 8322
            app.positionMotor(pos)
        end

        % Button pushed function: Sample6mTButton
        function Sample6mTButtonPushed(app, event)
            pos = app.maxPos - 128751 - 192324
            app.positionMotor(pos)
        end

        % Button pushed function: ReadpositionButton
        function ReadpositionButtonPushed(app, event)
            app.readMotorPos()
        end

        % Button pushed function: Moveup5mmButton
        function Moveup5mmButtonPushed(app, event)
            app.curPos = app.readMotorPos()
            app.positionMotor(app.curPos - 8000)
        end

        % Value changed function: CalibrationButton
        function CalibrationButtonValueChanged(app, event)
            value = app.CalibrationButton.Value;
            app.mCal = value 
        end

        % Button pushed function: Movedown5mmButton
        function Movedown5mmButtonPushed(app, event)
            app.curPos = app.readMotorPos()
            app.positionMotor(app.curPos + 8000)
        end

        % Value changed function: SlowascentButton
        function SlowascentButtonValueChanged(app, event)
            value = app.SlowascentButton.Value;
            if value == 1
                app.commandMotor("rup", 0, 20)
            end
            if value == 0 
                app.commandMotor("stop", 1, 1)
            end
        end

        % Value changed function: SlowdescentButton
        function SlowdescentButtonValueChanged(app, event)
            value = app.SlowdescentButton.Value;
            if value == 1
                app.commandMotor("rdn", 0, 20)
            end
            if value == 0
                app.commandMotor("stop", 1, 1)
            end
%             if app.STOPButtonPushed
%                 app.SlowdescentButton.Value = 0;
%             end
        end

        % Button pushed function: DisconnectmotorButton
        function DisconnectmotorButtonPushed(app, event)
            app.m = 0;
            app.mCon = 0;
            app.logit("Disconnected!")
        end

        % Value changed function: serialportnoCOMEditField_3
        function serialportnoCOMEditField_3ValueChanged(app, event)
            value = app.serialportnoCOMEditField_3.Value;
            
        end

        % Button pushed function: GotopositionButton
        function GotopositionButtonPushed(app, event)
            app.positionMotor(value)
        end

        % Value changed function: serialportnoCOMEditField_4
        function serialportnoCOMEditField_4ValueChanged(app, event)
            value = app.serialportnoCOMEditField_4.Value;
            
        end

        % Value changed function: bubblingIOButton
        function bubblingIOButtonValueChanged(app, event)
            value = app.bubblingIOButton.Value;
            bubblingtime = str2num(app.serialportnoCOMEditField_4.Value);

            if value == 1 && bubblingtime ~= 0
                running = true;
                tic;

                write(app.a, 'C', 'char');
                write(app.a, 'V', 'char');
            else
                running = false;
            end

%             if running == 0 
%                 write(app.a, 'c', 'char');
%                 write(app.a, 'v', 'char');
%                 class app.bubblingIOButton.Value;
%                 app.bubblingIOButton.Value = 0;
%                 value = 0;
%                 running = false;
%                 tmer = toc;
%                 app.logit(num2str(tmer));
%             end

            while running == true 
                running = app.bubblingIOButton.Value;
                tmer = toc;
                test = tmer - bubblingtime;
                if test > 0 
                    write(app.a, 'c', 'char');
                    write(app.a, 'v', 'char');
                    app.bubblingIOButton.Value = 0;                    
                    running = false;
                elseif running == false || app.shutdown == 1
                    write(app.a, 'c', 'char');
                    write(app.a, 'v', 'char');
                    app.bubblingIOButton.Value = 0;
                    running = false;
                    app.logit(num2str(tmer));
                    break
                end
                pause(0.001)
            end

        end

        % Value changed function: shutdownButton
        function shutdownButtonValueChanged(app, event)
            value = app.shutdownButton.Value;
            app.shutdown = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {394, 394};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {473, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.connectButton = uibutton(app.LeftPanel, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connectButton.Text = 'connect';
            app.disconnectButton = uibutton(app.LeftPanel, 'push');
            app.disconnectButton.ButtonPushedFcn = createCallbackFcn(app, @disconnectButtonPushed, true);
            app.disconnectButton.Text = 'disconnect';
            app.manualcontrolButton = uibutton(app.LeftPanel, 'state');
            app.manualcontrolButton.ValueChangedFcn = createCallbackFcn(app, @manualcontrolButtonValueChanged, true);
            app.manualcontrolButton.Text = 'manual control';
            app.NNvalveButton = uibutton(app.LeftPanel, 'state');
            app.NNvalveButton.ValueChangedFcn = createCallbackFcn(app, @NNvalveButtonValueChanged, true);
            app.NNvalveButton.Text = 'NN valve';
            app.OPHValveButton = uibutton(app.LeftPanel, 'state');
            app.OPHValveButton.ValueChangedFcn = createCallbackFcn(app, @OPHValveButtonValueChanged, true);
            app.OPHValveButton.Text = 'OPH Valve';
            app.InValveButton = uibutton(app.LeftPanel, 'state');
            app.InValveButton.ValueChangedFcn = createCallbackFcn(app, @InValveButtonValueChanged, true);
            app.InValveButton.Text = 'In Valve';
            app.OutValveButton = uibutton(app.LeftPanel, 'state');
            app.OutValveButton.ValueChangedFcn = createCallbackFcn(app, @OutValveButtonValueChanged, true);
            app.OutValveButton.Text = 'Out Valve';
            app.ShortValveButton = uibutton(app.LeftPanel, 'state');
            app.ShortValveButton.ValueChangedFcn = createCallbackFcn(app, @ShortValveButtonValueChanged, true);
            app.ShortValveButton.Text = 'Short Valve';
            app.serialportnoCOMEditField = uieditfield(app.LeftPanel, 'text');
            app.serialportnoCOMEditField.ValueChangedFcn = createCallbackFcn(app, @serialportnoCOMEditFieldValueChanged, true);
            app.serialportnoCOMEditFieldLabel = uilabel(app.LeftPanel);
            app.serialportnoCOMEditFieldLabel.Text = 'serial port no. - COM';
            app.lampButton = uibutton(app.LeftPanel, 'state');
            app.lampButton.ValueChangedFcn = createCallbackFcn(app, @lampButtonValueChanged, true);
            app.lampButton.Text = 'lamp';
            app.Lamp = uilamp(app.LeftPanel);
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.flushtimesEditFieldLabel = uilabel(app.LeftPanel);
            app.flushtimesEditFieldLabel.Text = 'flush time/s';
            app.flushtimesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.flushtimesEditField.ValueChangedFcn = createCallbackFcn(app, @flushtimesEditFieldValueChanged, true);
            app.PG1blackButton = uibutton(app.LeftPanel, 'state');
            app.PG1blackButton.ValueChangedFcn = createCallbackFcn(app, @PG1blackButtonValueChanged, true);
            app.PG1blackButton.Text = 'PG1 (black)';
            app.PG2blueButton = uibutton(app.LeftPanel, 'state');
            app.PG2blueButton.ValueChangedFcn = createCallbackFcn(app, @PG2blueButtonValueChanged, true);
            app.PG2blueButton.Text = 'PG2 (blue)';
            app.PG3greenButton = uibutton(app.LeftPanel, 'state');
            app.PG3greenButton.ValueChangedFcn = createCallbackFcn(app, @PG3greenButtonValueChanged, true);
            app.PG3greenButton.Text = 'PG3 (green)';
            app.graphButton = uibutton(app.LeftPanel, 'state');
            app.graphButton.ValueChangedFcn = createCallbackFcn(app, @graphButtonValueChanged, true);
            app.graphButton.Text = 'graph';
            app.cleargraphButton = uibutton(app.LeftPanel, 'push');
            app.cleargraphButton.ButtonPushedFcn = createCallbackFcn(app, @cleargraphButtonPushed, true);
            app.cleargraphButton.Text = 'clear graph';
            app.saveButton = uibutton(app.LeftPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.autosaveButton = uibutton(app.LeftPanel, 'state');
            app.autosaveButton.ValueChangedFcn = createCallbackFcn(app, @autosaveButtonValueChanged, true);
            app.autosaveButton.Text = 'autosave';
            app.shutdownButton = uibutton(app.LeftPanel, 'state');
            app.shutdownButton.ValueChangedFcn = createCallbackFcn(app, @shutdownButtonValueChanged, true);
            app.shutdownButton.Text = 'shutdown';
            app.PG4redButton = uibutton(app.LeftPanel, 'state');
            app.PG4redButton.ValueChangedFcn = createCallbackFcn(app, @PG4redButtonValueChanged, true);
            app.PG4redButton.Text = 'PG4 (red)';
            app.spinsolvectrlButton = uibutton(app.LeftPanel, 'state');
            app.spinsolvectrlButton.ValueChangedFcn = createCallbackFcn(app, @spinsolvectrlButtonValueChanged, true);
            app.spinsolvectrlButton.Text = 'spinsolve ctrl ';
            app.runssseqButton = uibutton(app.LeftPanel, 'push');
            app.runssseqButton.ButtonPushedFcn = createCallbackFcn(app, @runssseqButtonPushed, true);
            app.runssseqButton.Text = 'run ssseq';
            app.serialportnoCOMEditField_4 = uieditfield(app.LeftPanel, 'text');
            app.serialportnoCOMEditField_4.ValueChangedFcn = createCallbackFcn(app, @serialportnoCOMEditField_4ValueChanged, true);
            app.bubblingIOButton = uibutton(app.LeftPanel, 'state');
            app.bubblingIOButton.ValueChangedFcn = createCallbackFcn(app, @bubblingIOButtonValueChanged, true);
            app.bubblingIOButton.Text = 'bubbling I/O';
            app.maxbubblingtimeLabel = uilabel(app.LeftPanel);
            app.maxbubblingtimeLabel.Text = 'max. bubbling time';
            app.RightPanel = uipanel(app.GridLayout);
            app.serialportnoCOMEditField_2Label = uilabel(app.RightPanel);
            app.serialportnoCOMEditField_2Label.Text = 'serial port no. - COM';
            app.serialportnoCOMEditField_2 = uieditfield(app.RightPanel, 'text');
            app.serialportnoCOMEditField_2.ValueChangedFcn = createCallbackFcn(app, @serialportnoCOMEditField_2ValueChanged, true);
            app.serialportnoCOMEditField_2.ValueChangingFcn = createCallbackFcn(app, @serialportnoCOMEditField_2ValueChanging, true);
            app.FullydownButton = uibutton(app.RightPanel, 'push');
            app.FullydownButton.ButtonPushedFcn = createCallbackFcn(app, @FullydownButtonPushed, true);
            app.FullydownButton.Text = 'Fully down';
            app.FullyupButton = uibutton(app.RightPanel, 'push');
            app.FullyupButton.ButtonPushedFcn = createCallbackFcn(app, @FullyupButtonPushed, true);
            app.FullyupButton.Text = 'Fully up';
            app.ConnectmotorButton = uibutton(app.RightPanel, 'push');
            app.ConnectmotorButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectmotorButtonPushed, true);
            app.ConnectmotorButton.Text = 'Connect motor';
            app.STOPButton = uibutton(app.RightPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.SpinsolvemotorcontrolsLabel = uilabel(app.RightPanel);
            app.SpinsolvemotorcontrolsLabel.Text = 'Spinsolve motor controls';
            app.HallprobedownButton = uibutton(app.RightPanel, 'push');
            app.HallprobedownButton.ButtonPushedFcn = createCallbackFcn(app, @HallprobedownButtonPushed, true);
            app.HallprobedownButton.Text = 'Hall probe down';
            app.SampledownButton = uibutton(app.RightPanel, 'push');
            app.SampledownButton.ButtonPushedFcn = createCallbackFcn(app, @SampledownButtonPushed, true);
            app.SampledownButton.Text = 'Sample down';
            app.Sample6mTButton = uibutton(app.RightPanel, 'push');
            app.Sample6mTButton.ButtonPushedFcn = createCallbackFcn(app, @Sample6mTButtonPushed, true);
            app.Sample6mTButton.Text = 'Sample 6 mT';
            app.DisconnectmotorButton = uibutton(app.RightPanel, 'push');
            app.DisconnectmotorButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectmotorButtonPushed, true);
            app.DisconnectmotorButton.Text = 'Disconnect motor';
            app.ReadpositionButton = uibutton(app.RightPanel, 'push');
            app.ReadpositionButton.ButtonPushedFcn = createCallbackFcn(app, @ReadpositionButtonPushed, true);
            app.ReadpositionButton.Text = 'Read position';
            app.SlowascentButton = uibutton(app.RightPanel, 'state');
            app.SlowascentButton.ValueChangedFcn = createCallbackFcn(app, @SlowascentButtonValueChanged, true);
            app.SlowascentButton.Text = 'Slow ascent';
            app.SlowdescentButton = uibutton(app.RightPanel, 'state');
            app.SlowdescentButton.ValueChangedFcn = createCallbackFcn(app, @SlowdescentButtonValueChanged, true);
            app.SlowdescentButton.Text = 'Slow descent';
            app.Moveup5mmButton = uibutton(app.RightPanel, 'push');
            app.Moveup5mmButton.ButtonPushedFcn = createCallbackFcn(app, @Moveup5mmButtonPushed, true);
            app.Moveup5mmButton.Text = 'Move up 5 mm';
            app.Movedown5mmButton = uibutton(app.RightPanel, 'push');
            app.Movedown5mmButton.ButtonPushedFcn = createCallbackFcn(app, @Movedown5mmButtonPushed, true);
            app.Movedown5mmButton.Text = 'Move down 5 mm';
            app.CalibrationButton = uibutton(app.RightPanel, 'state');
            app.CalibrationButton.ValueChangedFcn = createCallbackFcn(app, @CalibrationButtonValueChanged, true);
            app.CalibrationButton.Text = 'Calibration';
            app.serialportnoCOMEditField_3 = uieditfield(app.RightPanel, 'text');
            app.serialportnoCOMEditField_3.ValueChangedFcn = createCallbackFcn(app, @serialportnoCOMEditField_3ValueChanged, true);
            app.GotopositionButton = uibutton(app.RightPanel, 'push');
            app.GotopositionButton.ButtonPushedFcn = createCallbackFcn(app, @GotopositionButtonPushed, true);
            app.GotopositionButton.Text = 'Go to position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = electronics_control_9_S5(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end